classdef BodePlotMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        OverlaybodeGplotCheckBox  matlab.ui.control.CheckBox
        NUMEditFieldLabel         matlab.ui.control.Label
        NUMEditField              matlab.ui.control.EditField
        DENEditFieldLabel         matlab.ui.control.Label
        DENEditField              matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        GsLabel                   matlab.ui.control.Label
        PLOTButton                matlab.ui.control.Button
        HelloLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OverlaybodeGplotCheckBox
        function OverlaybodeGplotCheckBoxValueChanged(app, event)
            value = app.OverlaybodeGplotCheckBox.Value;

        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            cla(app.UIAxes)
            hold(app.UIAxes,'on');
            cla(app.UIAxes_2)
            hold(app.UIAxes_2,'on');
            Num = eval(app.NUMEditField.Value);
            Den = eval(app.DENEditField.Value);
            G = zpk(tf(Num,Den));
            app.HelloLabel.Text = evalc('G');
            Flag = app.OverlaybodeGplotCheckBox.Value;
            
            [Z,P,K] = zpkdata(G);
            Z = Z{1};
            P = P{1};

            %Loop through Poles and Zeros and Put
            % in order and sort by increasing
            % order of sigma or wn
            MyRoots = []; % abs value
            MyOrder = []; % Neg is a zero
            MyStable = []; % Neg is LHP
            MyMult = []; % Multiplicity
            MyGain = []; % Similar to Stable 
            jj = 1;
            ii = 1;

            %%%%%   POLES %%%%%%%%%
            while ii<=length(P)
                if isreal(P(ii))
                    MyRoots(jj)=abs(P(ii));
                    MyOrder(jj) = 1;
                    MyStable(jj) = sign(P(ii));
                    MyGain(jj) = -MyStable(jj);
                    MyMult(jj) = sum(abs(P-P(ii))<1e-6);
                    ii = ii + 1*(MyMult(jj));
                    jj = jj + 1;
                else
                    MyRoots(jj) = abs(P(ii));
                    MyOrder(jj) = 2;
                    if real(P(ii))==0
                        MyStable(jj) = -1;
                    else
                        MyStable(jj) = sign(real(P(ii)));
                    end
                    MyGain(jj) = sign(P(ii)*P(ii+1));
                    MyMult(jj) = sum(abs(P-P(ii))<1e-6);
                    ii = ii + 2*(MyMult(jj));
                    jj = jj + 1;
                end
            end

            %%%%%   ZEROS %%%%%%%%%
            ii = 1;
            while ii<=length(Z)
                if isreal(Z(ii))
                    MyRoots(jj)=abs(Z(ii));
                    MyOrder(jj) = -1;
                    MyStable(jj) = sign(Z(ii));
                    MyGain(jj) = -MyStable(jj);
                    MyMult(jj) = sum(abs(Z-Z(ii))<1e-6);
                    ii = ii + 1*(MyMult(jj));
                    jj = jj + 1;
                else
                    MyRoots(jj) = abs(Z(ii));
                    MyOrder(jj) = -2;
                    if real(Z(ii))==0
                        MyStable(jj) = -1;
                    else
                        MyStable(jj) = sign(real(Z(ii)));
                    end
                    MyGain(jj) = sign(Z(ii)*Z(ii+1));
                    MyMult(jj) = sum(abs(Z-Z(ii))<1e-6);
                    ii = ii + 2*(MyMult(jj));
                    jj = jj + 1;
                end
            end

            %%%%%   SORT %%%%%%%%%
            [MyRoots I] = sort(MyRoots');
            MyOrder = MyOrder(I)';
            MyStable = MyStable(I)';
            MyMult = MyMult(I)';
            MyGain = MyGain(I)';

            %%%%%   DC GAIN %%%%%%%%%
            Khat = K;
            if MyRoots(1)==0;
                for ii = 2:length(MyRoots);
                    Khat = Khat*MyGain(ii)*MyRoots(ii)^(-MyOrder(ii)*MyMult(ii));
                end
            else
                for ii = 1:length(MyRoots);
                    Khat = Khat*MyGain(ii)*MyRoots(ii)^(-MyOrder(ii)*MyMult(ii));
                end
            end


            %%%%%   MAGNITUDE PLOT %%%%%%%%%

            %%%%% first line-segmant %%%%%%%%%
            if MyRoots(1)==0;
                DW = log10(MyRoots(2)) - log10(1);
                Slope = -20*MyOrder(1)*MyMult(1);
                Break = 20*log10(abs(Khat)) + DW*Slope;
                Point(:,2) = [log10(MyRoots(2));Break];
                Min = log10(MyRoots(2))-1;
                Point(:,1) = [Min;Break-Slope];
                plot(app.UIAxes,Point(1,[1 2]),Point(2,[1 2]),'linewidth',4)
            else
                Min = log10(MyRoots(1))-1;
                Slope = 0;
                plot(app.UIAxes,[Min,log10(MyRoots(1))],[20*log10(abs(Khat)), 20*log10(abs(Khat))],'linewidth',4)
                Point(:,1) = [log10(MyRoots(1));20*log10(abs(Khat))];
                DW = log10(MyRoots(2)) - log10(MyRoots(1));
                Slope = -20*MyOrder(1)*MyMult(1)+Slope;
                Point(:,2) = [log10(MyRoots(2));Point(2,1)+Slope*DW];
                plot(app.UIAxes,Point(1,[1 2]),Point(2,[1 2]),'linewidth',4)
            end

            %%%%% The rest of the line-segmants %%%%%%%%%
            if length(MyRoots)>2
    
                for ii = 2:length(MyRoots)-1
                    DW = log10(MyRoots(ii+1)) - log10(MyRoots(ii));
                    Slope = -20*MyOrder(ii)*MyMult(ii)+Slope;
                    Point(:,ii+1) = [log10(MyRoots(ii+1));Point(2,ii)+Slope*DW];
                    plot(app.UIAxes,Point(1,[ii ii+1]),Point(2,[ii ii+1]),'linewidth',4)
                end
                ii = ii+1;

                Slope = -20*MyOrder(ii)*MyMult(ii)+Slope;
                Max = log10(MyRoots(ii))+1;
                Point(:,ii+1) = [Max;Point(2,ii)+Slope];
                plot(app.UIAxes,Point(1,[ii ii+1]),Point(2,[ii ii+1]),'linewidth',4)

            else
 
                Slope = -20*MyOrder(2)*MyMult(2)+Slope;
                Max = log10(MyRoots(2))+1;
                Point(:,3) = [Max;Point(2,2)+Slope];
                plot(app.UIAxes,Point(1,[2 3]),Point(2,[2 3]),'linewidth',4)

            end

            %%%%% Plot Mag from bode(G) Plot on top %%%%%%%%%
            if Flag==1
                W = 10^Min:.001:10^Max;
                [Mag PhaseBode] = bode(G,W);
                plot(app.UIAxes,log10(W),20*log10(abs(squeeze(Mag))),'k--.','linewidth',4)
            end
            
            %%%%%   PHASE PLOT %%%%%%%%%

            if sign(Khat)<0
                Phase = -180;
            else
                Phase = 0;
            end

            if MyRoots(1)==0;
                Phase = -90*MyOrder(1)*MyMult(1)+Phase;
                Start = 2;
            else
                Start = 1;
            end

            PhaseW = [];
            PhaseSlope = [];
            for ii = Start:length(MyRoots)
                PhaseW = [PhaseW, [MyRoots(ii)/10 10*MyRoots(ii)]];
                Slope = 45*MyOrder(ii)*MyMult(ii)*MyStable(ii);
                PhaseSlope = [PhaseSlope, [Slope -Slope]];
            end


            [PhaseW I] = sort(PhaseW);
            PhaseSlope = PhaseSlope(I);

            Min = log10(PhaseW(1))-1;
            plot(app.UIAxes_2,[Min log10(PhaseW(1))],[Phase Phase],'linewidth',4) 

            for ii = 1:length(PhaseW)-1;
                DW = log10(PhaseW(ii+1))-log10(PhaseW(ii));
                Phase(ii+1) = Phase(ii) + sum(PhaseSlope(1:ii))*DW;
                plot(app.UIAxes_2,[log10(PhaseW(ii)) log10(PhaseW(ii+1))],[Phase(ii) Phase(ii+1)],'linewidth',4)
            end

            Max = log10(PhaseW(end))+1;
            plot(app.UIAxes_2,[log10(PhaseW(end)) Max],[Phase(end) Phase(end)],'linewidth',4) 

            %%%%% Plot Mag from bode(G) Plot on top %%%%%%%%%
            if Flag==1
                W = 10^Min:.001:10^Max;
                [Mag PhaseBode] = bode(G,W);
                Bias = Phase(1) - PhaseBode(1);
                if Bias>300
                    Bias = 360;
                elseif Bias<-300
                    Bias = -360;
                else
                    Bias = 0;
                end
                plot(app.UIAxes_2,log10(W),squeeze(PhaseBode)+Bias,'k--.','linewidth',4)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bode Sketch')
            ylabel(app.UIAxes, 'Mag (dB)')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'Freq (rad/s)')
            ylabel(app.UIAxes_2, 'Phase (deg)')
            app.OverlaybodeGplotCheckBox = uicheckbox(app.UIFigure);
            app.OverlaybodeGplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlaybodeGplotCheckBoxValueChanged, true);
            app.OverlaybodeGplotCheckBox.Text = 'Overlay bode(G) plot';
            app.NUMEditFieldLabel = uilabel(app.UIFigure);
            app.NUMEditFieldLabel.Text = 'NUM';
            app.NUMEditField = uieditfield(app.UIFigure, 'text');
            app.NUMEditField.Value = '[1 1]';
            app.DENEditFieldLabel = uilabel(app.UIFigure);
            app.DENEditFieldLabel.Text = 'DEN';
            app.DENEditField = uieditfield(app.UIFigure, 'text');
            app.DENEditField.Value = 'conv([1 3],[1 .1 7])';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '________________________________';
            app.GsLabel = uilabel(app.UIFigure);
            app.GsLabel.Text = 'G(s) = ';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.HelloLabel = uilabel(app.UIFigure);
            app.HelloLabel.Text = 'Hello';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BodePlotMaker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end